classdef SarcLen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SarcLenUIFigure                matlab.ui.Figure
        MeanSL_ACF                     matlab.ui.control.NumericEditField
        MeanSL_FFT                     matlab.ui.control.NumericEditField
        MeanSLumLabel                  matlab.ui.control.Label
        BoxSelectionDropDown           matlab.ui.control.DropDown
        BoxSelectionDropDownLabel      matlab.ui.control.Label
        NewBoxButton                   matlab.ui.control.Button
        LoadImageButton                matlab.ui.control.Button
        SarcomereLengthCalculationPanel  matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        ACFSarcomereLengthumEditField  matlab.ui.control.NumericEditField
        ACFSarcomereLengthumEditFieldLabel  matlab.ui.control.Label
        FFTSarcomereLengthumEditField  matlab.ui.control.NumericEditField
        FFTSarcomereLengthumEditFieldLabel  matlab.ui.control.Label
        ac_axes                        matlab.ui.control.UIAxes
        fft_axes_inset                 matlab.ui.control.UIAxes
        fft_axes                       matlab.ui.control.UIAxes
        BrightfieldPanel               matlab.ui.container.Panel
        BoxWidthEditField              matlab.ui.control.NumericEditField
        BoxWidthEditFieldLabel         matlab.ui.control.Label
        BoxHeightEditField             matlab.ui.control.NumericEditField
        BoxHeightEditFieldLabel        matlab.ui.control.Label
        MicroscopeCalibrationumpxEditField  matlab.ui.control.NumericEditField
        MicroscopeCalibrationumpxEditFieldLabel  matlab.ui.control.Label
        RegionofInterestROILabel       matlab.ui.control.Label
        BrightfieldImageLabel          matlab.ui.control.Label
        calculation_axes               matlab.ui.control.UIAxes
        px_intensity                   matlab.ui.control.UIAxes
        inset_axes                     matlab.ui.control.UIAxes
        image_axes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        image_file % Description
        roi_pos
        roi_rec
        px_cal
        roi_box
        mean_profile
        sig
    end
    
    properties (Access = public)
        sl_data % Description
    end

    methods (Access = private)
        
        function ClearDisplay(app)
            cla(app.image_axes)
            cla(app.inset_axes)
            cla(app.px_intensity)
            cla(app.calculation_axes)
            cla(app.fft_axes)
            cla(app.fft_axes_inset)
            cla(app.ac_axes)
            app.ACFSarcomereLengthumEditField.Value = 0;
            app.FFTSarcomereLengthumEditField.Value = 0;
            app.MicroscopeCalibrationumpxEditField.Value = 0.000;
            app.BoxHeightEditField.Value = 0.000;
            app.BoxWidthEditField.Value = 0.000;
        end
        
    end
    
    methods (Access = public)
        
        function SarcomereLength(app)
            cla(app.px_intensity);
            cla(app.inset_axes);
            cla(app.calculation_axes);
            cla(app.ac_axes);
            cla(app.fft_axes);
            cla(app.fft_axes_inset);
            box_no = str2num(app.BoxSelectionDropDown.Value);
            app.mean_profile = [];
            app.roi_box = [];
            app.roi_box = imcrop(app.image_file, ...
                app.sl_data.box_handle(box_no).Position);
            center_image_with_preserved_aspect_ratio( ...
                app.roi_box, ...
                app.inset_axes);
            [m,n] = size(app.roi_box);
            p = app.sl_data.box_handle(box_no).Position;
            set(app.sl_data.box_label(box_no),'String',sprintf('%.0f',box_no));
            set(app.sl_data.box_label(box_no), ...
                'Position',[p(1)+p(3)+20 ...
                p(2)-30]);
            hold(app.px_intensity,"on")
            for j = 1 : m
                plot(app.px_intensity,app.roi_box(j,:),'LineStyle',":","LineWidth",0.25);
                mp(j) = max(app.roi_box(j,:));
            end
            
            ylim(app.px_intensity,[0 max(max(app.image_file))])
            app.mean_profile = mean(app.roi_box,1);
            w = hann(length(app.mean_profile));
            app.sig = app.mean_profile'.*w;
            hold(app.calculation_axes,"on")
            plot(app.calculation_axes,app.mean_profile,'Color','b',"LineWidth",2)
            plot(app.calculation_axes, app.sig,'Color','r',"LineWidth",2)
            xlim(app.px_intensity, [1 numel(app.mean_profile)])
            xlim(app.calculation_axes, [1 numel(app.mean_profile)])
            ylim(app.calculation_axes,[0 max(max(app.image_file))])

            legend(app.calculation_axes,"Mean Intensity", ...
                "Hann Windowed Mean Intensity",'Location',"south")
            [sl_fft] = SarcLenFFT(app);
            app.sl_data.sarcomere_length_fft(box_no) = sl_fft;
            [sl_ac] = SarcLenAutoCorr(app);
            app.sl_data.sarcomere_length_acf(box_no) = sl_ac;
        end
        
        function sl_ac = SarcLenAutoCorr(app)
            x = app.mean_profile;
            [X_acf,lags] = autocorr(x,NumLags=round(numel(x)/2));
            plot(app.ac_axes,lags,X_acf,'LineWidth',2,'color','b')
            [lambda,fit_parameters,r_squared,y_fit] = fit_damped_sine_wave('y_data',X_acf,...
                'x_data',0:round(numel(x)/2), 'min_x_index_spacing',5);
            hold(app.ac_axes,"on")
            plot(app.ac_axes,lags,y_fit,'d','color','g','LineWidth',2)
            t = sprintf('Period  = %.3f',fit_parameters(4));
            text(app.ac_axes,round(0.7*numel(x))-40,0.8,t)
            xlim(app.ac_axes,[0 round(numel(x)/2)])
            ylim(app.ac_axes,[-1 1])
            if app.px_cal ~=0
                try
                    app.ACFSarcomereLengthumEditField.Value = fit_parameters(4) * app.px_cal;
                catch
                    app.ACFSarcomereLengthumEditField.Value = 0;
                end
                sl_ac = fit_parameters(4) * app.px_cal;
            else
                sl_ac = 0;
            end
        end
        
        function sl_fft = SarcLenFFT(app)
            x = app.sig;
            X = fft(x);
            L = numel(X);
            P2 = abs(X/L);
            [peaks, locs] = findpeaks(P2);
            [max_mag,max_ix] = max(peaks);
            F_p = 0:numel(X)-1;
            hold(app.fft_axes,"on")
            plot(app.fft_axes,F_p,P2,'Color','r','LineWidth',2)
            plot(app.fft_axes,F_p(locs(max_ix)),P2(locs(max_ix)),'o', ...
                'MarkerSize',5,"Color",'g')
            xlim(app.fft_axes,[0 numel(P2)-1])
            hold(app.fft_axes_inset,"on")
            fft_ix = (1:numel(P2)-1)/(numel(P2)*app.px_cal);
            fft_ix = [0 fft_ix];
            plot(app.fft_axes_inset,fft_ix,P2,'Color','r','LineWidth',2)
            plot(app.fft_axes_inset,fft_ix(locs(max_ix)),P2(locs(max_ix)),'o', ...
                'MarkerSize',15,"Color",'g')
            t = sprintf('Peak is at %.3f px^{-1}',fft_ix(locs(max_ix)));
            ylim(app.fft_axes_inset,[0 max(peaks)+5])
            xlim(app.fft_axes_inset,[0 fft_ix(locs(max_ix))+1])
            text(app.fft_axes_inset,fft_ix(locs(max_ix)),max(peaks)+2,t)
            
            if app.px_cal ~=0
                try
                    app.FFTSarcomereLengthumEditField.Value = numel(x)*app.px_cal/F_p(locs(max_ix));
                catch
                    app.FFTSarcomereLengthumEditField.Value = 0;       
                end
                sl_fft = numel(x)*app.px_cal/F_p(locs(max_ix));
            else
                sl_fft = 0;
            end
        end
        
        
        function UpdateTable(app)
            
            for i = 1:numel(app.sl_data.sarcomere_length_fft)
                s.box_no{i,1} = sprintf('%i',i);
                s.fft_sl{i,1} = sprintf('%.3f',app.sl_data.sarcomere_length_fft(i));
                s.acf_sl{i,1} = sprintf('%.3f',app.sl_data.sarcomere_length_acf(i));
            end
            
            s.box_no{end+1,1} = 'Average';
            s.fft_sl{end+1,1} = sprintf('%.3f',mean(app.sl_data.sarcomere_length_fft));
            s.acf_sl{end+1,1} = sprintf('%.3f',mean(app.sl_data.sarcomere_length_acf));
            
            t = struct2table(s);
            
            app.UITable.Data = t;
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            colormap(app.SarcLenUIFigure, 'gray');
            addpath(genpath('utilities'))
            movegui(app.SarcLenUIFigure,'center')


        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file_string,path_string] = uigetfile2(filterspec);
            if (path_string~=0)
                ClearDisplay(app)
                app.sl_data = [];
                app.UITable.Data = [];
                im_file = fullfile(path_string,file_string);
                im = imread(im_file);
                if (ndims(im)==3)
                    im = rgb2gray(im);
                end
                center_image_with_preserved_aspect_ratio( ...
                    im, ...
                    app.image_axes);
                app.image_file = im;
            end
        end

        % Button pushed function: NewBoxButton
        function NewBoxButtonPushed(app, event)
            
            if (~isfield(app.sl_data,'box_handle'))
                n=1;
                app.sl_data.box_handle(n) = drawrectangle(app.image_axes);
                p = app.sl_data.box_handle(n).Position;
                app.sl_data.old_width = p(3);
                app.sl_data.old_height = p(4);
            else
                n = 1 + numel(app.sl_data.box_handle);
                p = app.sl_data.box_handle(n-1).Position;

                app.sl_data.box_handle(n) = images.roi.Rectangle(app.image_axes, ...
                    'Position',p + [150,0,0,0]);
                for i=1:(n-1)
                    app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                end
                
            end
            
            app.sl_data.box_handle(n).Color = [0 1 0];
            app.sl_data.box_handle(n).FaceAlpha = 0;
            for i=1:(n-1)
                app.sl_data.box_handle(i).Color = [1 0 0];
            end
            
            app.sl_data.box_label(n) = text(app.image_axes, ...
                p(1)+p(3)+20,p(2)-30,sprintf('%.0f',n),'FontWeight',"bold","FontSize",18,"Color",'w');
            
            app.roi_pos = get(app.roi_rec, 'Position');
            app.px_cal = app.MicroscopeCalibrationumpxEditField.Value;
            app.BoxHeightEditField.Value = app.sl_data.box_handle(n).Position(4);  
            app.BoxWidthEditField.Value = app.sl_data.box_handle(n).Position(3);
            
            for i=1:n
                control_strings{i}=sprintf('%.0f',i);
            end

            app.BoxSelectionDropDown.Items = control_strings;
            app.BoxSelectionDropDown.Value = control_strings{n};

            SarcomereLength(app)
            UpdateTable(app);
            addlistener(app.sl_data.box_handle(n),"MovingROI",@(src,evt) UpdateSL(evt));

            function UpdateSL(evt)
                box_no = app.BoxSelectionDropDown.Value;
                app.BoxHeightEditField.Value = app.sl_data.box_handle(n).Position(4);  
                app.BoxWidthEditField.Value = app.sl_data.box_handle(n).Position(3);
                cla(app.px_intensity)
                cla(app.calculation_axes)
                cla(app.fft_axes)
                cla(app.fft_axes_inset)
                cla(app.ac_axes)
                SarcomereLength(app)
                UpdateTable(app);
            end

        end

        % Value changed function: MicroscopeCalibrationumpxEditField
        function MicroscopeCalibrationumpxEditFieldValueChanged(app, event)
            app.px_cal = app.MicroscopeCalibrationumpxEditField.Value;
            if isfield(app.sl_data,'box_handle')
            SarcomereLength(app)
            UpdateTable(app)
            end
        end

        % Value changed function: BoxSelectionDropDown
        function BoxSelectionDropDownValueChanged(app, event)
            selected_box = str2num(app.BoxSelectionDropDown.Value);
            control_strings = app.BoxSelectionDropDown.Items;      

            n = numel(control_strings);
            for i=1:n
                if (i~=selected_box)
                    app.sl_data.box_handle(i).Color = [1 0 0];
                    app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                else
                    app.sl_data.box_handle(i).Color = [0 1 0];
                    app.sl_data.box_handle(i).InteractionsAllowed = 'all';
                end
            end
            SarcomereLength(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SarcLenUIFigure = uifigure('Visible', 'off');
            app.SarcLenUIFigure.Name = 'SarcLen';
            app.BrightfieldPanel = uipanel(app.SarcLenUIFigure);
            app.BrightfieldPanel.Title = 'Brightfield Panel';
            app.image_axes = uiaxes(app.BrightfieldPanel);
            zlabel(app.image_axes, 'Z')
            app.image_axes.XTick = [];
            app.image_axes.YTick = [];
            app.inset_axes = uiaxes(app.BrightfieldPanel);
            zlabel(app.inset_axes, 'Z')
            app.inset_axes.XTick = [];
            app.inset_axes.YTick = [];
            app.px_intensity = uiaxes(app.BrightfieldPanel);
            title(app.px_intensity, 'Intensity Profiles')
            xlabel(app.px_intensity, 'ROI Index')
            ylabel(app.px_intensity, 'Optical Intensity (A.U)')
            zlabel(app.px_intensity, 'Z')
            app.calculation_axes = uiaxes(app.BrightfieldPanel);
            title(app.calculation_axes, 'Intensity Profile for SL Calculation')
            xlabel(app.calculation_axes, 'ROI Index')
            ylabel(app.calculation_axes, 'Optical Intensity (A.U)')
            zlabel(app.calculation_axes, 'Z')
            app.BrightfieldImageLabel = uilabel(app.BrightfieldPanel);
            app.BrightfieldImageLabel.Text = 'Brightfield Image';
            app.RegionofInterestROILabel = uilabel(app.BrightfieldPanel);
            app.RegionofInterestROILabel.Text = 'Region of Interest (ROI)';
            app.MicroscopeCalibrationumpxEditFieldLabel = uilabel(app.BrightfieldPanel);
            app.MicroscopeCalibrationumpxEditFieldLabel.Text = 'Microscope Calibration (um/px)';
            app.MicroscopeCalibrationumpxEditField = uieditfield(app.BrightfieldPanel, 'numeric');
            app.MicroscopeCalibrationumpxEditField.ValueDisplayFormat = '%.3f';
            app.MicroscopeCalibrationumpxEditField.ValueChangedFcn = createCallbackFcn(app, @MicroscopeCalibrationumpxEditFieldValueChanged, true);
            app.BoxHeightEditFieldLabel = uilabel(app.BrightfieldPanel);
            app.BoxHeightEditFieldLabel.Text = 'Box Height';
            app.BoxHeightEditField = uieditfield(app.BrightfieldPanel, 'numeric');
            app.BoxHeightEditField.ValueDisplayFormat = '%.3f';
            app.BoxWidthEditFieldLabel = uilabel(app.BrightfieldPanel);
            app.BoxWidthEditFieldLabel.Text = 'Box Width';
            app.BoxWidthEditField = uieditfield(app.BrightfieldPanel, 'numeric');
            app.BoxWidthEditField.ValueDisplayFormat = '%.3f';
            app.SarcomereLengthCalculationPanel = uipanel(app.SarcLenUIFigure);
            app.SarcomereLengthCalculationPanel.Title = 'Sarcomere Length Calculation';
            app.fft_axes = uiaxes(app.SarcomereLengthCalculationPanel);
            title(app.fft_axes, 'FFT: Double-Sided Spectrum')
            xlabel(app.fft_axes, 'FFT Index')
            ylabel(app.fft_axes, 'Amplitude (A.U.)')
            zlabel(app.fft_axes, 'Z')
            app.fft_axes_inset = uiaxes(app.SarcomereLengthCalculationPanel);
            xlabel(app.fft_axes_inset, 'Pixel^{-1}')
            ylabel(app.fft_axes_inset, 'Amplitude (A.U.)')
            zlabel(app.fft_axes_inset, 'Z')
            app.ac_axes = uiaxes(app.SarcomereLengthCalculationPanel);
            title(app.ac_axes, 'ACF')
            xlabel(app.ac_axes, 'Lags')
            ylabel(app.ac_axes, 'Amplitude (A.U.)')
            zlabel(app.ac_axes, 'Z')
            app.FFTSarcomereLengthumEditFieldLabel = uilabel(app.SarcomereLengthCalculationPanel);
            app.FFTSarcomereLengthumEditFieldLabel.Text = 'FFT Sarcomere Length (um)';
            app.FFTSarcomereLengthumEditField = uieditfield(app.SarcomereLengthCalculationPanel, 'numeric');
            app.FFTSarcomereLengthumEditField.Editable = 'off';
            app.ACFSarcomereLengthumEditFieldLabel = uilabel(app.SarcomereLengthCalculationPanel);
            app.ACFSarcomereLengthumEditFieldLabel.Text = 'ACF Sarcomere Length (um)';
            app.ACFSarcomereLengthumEditField = uieditfield(app.SarcomereLengthCalculationPanel, 'numeric');
            app.ACFSarcomereLengthumEditField.Editable = 'off';
            app.UITable = uitable(app.SarcomereLengthCalculationPanel);
            app.UITable.ColumnName = {'Box No'; 'FFT SL'; 'ACF SL'};
            app.UITable.RowName = {};
            app.LoadImageButton = uibutton(app.SarcLenUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.NewBoxButton = uibutton(app.SarcLenUIFigure, 'push');
            app.NewBoxButton.ButtonPushedFcn = createCallbackFcn(app, @NewBoxButtonPushed, true);
            app.NewBoxButton.Text = 'New Box';
            app.BoxSelectionDropDownLabel = uilabel(app.SarcLenUIFigure);
            app.BoxSelectionDropDownLabel.Text = 'Box Selection';
            app.BoxSelectionDropDown = uidropdown(app.SarcLenUIFigure);
            app.BoxSelectionDropDown.Items = {};
            app.BoxSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @BoxSelectionDropDownValueChanged, true);
            app.BoxSelectionDropDown.Placeholder = 'No Data';
            app.BoxSelectionDropDown.Value = {};
            app.MeanSLumLabel = uilabel(app.SarcLenUIFigure);
            app.MeanSLumLabel.Text = 'Mean SL (um)';
            app.MeanSL_FFT = uieditfield(app.SarcLenUIFigure, 'numeric');
            app.MeanSL_ACF = uieditfield(app.SarcLenUIFigure, 'numeric');
            % Show the figure after all components are created
            app.SarcLenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SarcLen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SarcLenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SarcLenUIFigure)
        end
    end
end