classdef SLMeasure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLMeasureUIFigure           matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMeasurementMenu         matlab.ui.container.Menu
        LoadMeasurementMenu         matlab.ui.container.Menu
        CameraPanel                 matlab.ui.container.Panel
        ResolutionPanel             matlab.ui.container.Panel
        ResolutionDropDown          matlab.ui.control.DropDown
        ColorAdjustmentPanel        matlab.ui.container.Panel
        WhiteBalancePanel           matlab.ui.container.Panel
        ExposurePanel               matlab.ui.container.Panel
        SnapshotRecordPanel         matlab.ui.container.Panel
        DeviceList                  matlab.ui.control.ListBox
        DeviceListPanel             matlab.ui.container.Panel
        StartLiveMeasurementButton  matlab.ui.control.StateButton
        MicroscopeCalibrationumpxDropDown  matlab.ui.control.DropDown
        MicroscopeCalibrationumpxDropDownLabel  matlab.ui.control.Label
        DeleteBoxButton             matlab.ui.control.Button
        BoxSelectionDropDown        matlab.ui.control.DropDown
        BoxSelectionDropDownLabel   matlab.ui.control.Label
        NewBoxButton                matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        SarcomereLengthCalculationPanel  matlab.ui.container.Panel
        ExportSLTableButton         matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        ac_axis                     matlab.ui.control.UIAxes
        fft_axis                    matlab.ui.control.UIAxes
        BrightfieldPanel            matlab.ui.container.Panel
        ROIRowSelectSpinner         matlab.ui.control.Spinner
        ROIRowSelectSpinnerLabel    matlab.ui.control.Label
        ROIWidthpxEditField         matlab.ui.control.NumericEditField
        ROIWidthpxEditFieldLabel    matlab.ui.control.Label
        ROIHeightpxEditField        matlab.ui.control.NumericEditField
        ROIHeightpxEditFieldLabel   matlab.ui.control.Label
        calculation_axis            matlab.ui.control.UIAxes
        px_intensity                matlab.ui.control.UIAxes
        inset_axis                  matlab.ui.control.UIAxes
        image_axis                  matlab.ui.control.UIAxes
    end


    properties (Access = private)

    end

    properties (Access = public)
        image_file % Description
        roi_pos
        roi_rec
        px_cal
        roi_box
        profile
        sig
        background
        sl_data
        P2 
        lags
        X_acf 
        wavelength 
        y_fit 
        r_squared 
        mean_X_acf
        mean_P2
        live_image = []
        ToupcamData
    end

    methods (Access = private)

        function ClearDisplay(app)
            cla(app.image_axis)
            cla(app.inset_axis)
            cla(app.px_intensity)
            cla(app.calculation_axis)
            cla(app.fft_axis)
            cla(app.ac_axis)
            app.ROIHeightpxEditField.Value = 0;
            app.ROIWidthpxEditField.Value = 0;
        end

    end

    methods (Access = public)

        function SarcomereLength(app)
            cla(app.px_intensity);
            cla(app.inset_axis);
            cla(app.calculation_axis);
            cla(app.ac_axis);
            cla(app.fft_axis);
            app.profile = [];
            app.roi_box = [];
            
            box_no = str2num(app.BoxSelectionDropDown.Value);
%             Box Resize if neccessary
%             n = numel(app.sl_data.box_handle);
%             for i=1:n
%                 p(i,1:4) = app.sl_data.box_handle(i).Position;
%             end
%             w = p(box_no,3);
%             h = p(box_no,4);
%             if ((w~=app.sl_data.old_width)|(h~=app.sl_data.old_height))
%                 for i=1:n
%                     p(i,3) = w;
%                     p(i,4) = h;
%                     app.sl_data.box_handle(i).Position = p(i,:);
%                 end
%             end

            app.roi_box = imcrop(app.sl_data.image_file, ...
                app.sl_data.box_handle(box_no).Position);
            center_image_with_preserved_aspect_ratio( ...
                app.roi_box, ...
                app.inset_axis);
            

            [m,n] = size(app.roi_box);
            app.ROIHeightpxEditField.Value = m;
            app.ROIWidthpxEditField.Value = n;

            app.ROIRowSelectSpinner.Limits = [1,m];
            p = app.sl_data.box_handle(box_no).Position;
            set(app.sl_data.box_label(box_no),'String',sprintf('%.0f',box_no));
            set(app.sl_data.box_label(box_no), ...
                'Position',[p(1)+p(3)+20 ...
                p(2)-30]);

            app.profile = [];
            app.sig = [];
            sl_fft = [];
            sl_acf = [];
            app.P2 = [];
            app.X_acf = [];
            app.wavelength = [];
            app.lags = [];
            app.y_fit = [];
            app.r_squared = [];

            for ct = 1 : size(app.roi_box,1)
                app.profile(ct,:) = app.roi_box(ct,:);
                app.sig(ct,:)  = (diff(app.profile(ct,:)));
                SLFFT(app,ct);
                SLAutoCorr(app,ct);
            end
            
            app.mean_X_acf = mean(app.X_acf,1);
            app.mean_P2 = mean(app.P2,1);
            
            [lambda,fit_parameters,app.r_squared,app.y_fit] = fit_damped_sine_wave('y_data',app.mean_X_acf,...
                'x_data',app.lags(1,:), 'min_x_index_spacing',5);
            if app.px_cal ~=0
                app.sl_data.sarcomere_length_acf(box_no) = lambda * app.px_cal;
                app.wavelength = lambda;
            else
                app.sl_data.sarcomere_length_acf(box_no) = 0;
            end
            
            [peaks, locs] = findpeaks(app.mean_P2);
            [max_mag,max_ix] = max(peaks);
            F_p = 0:numel(app.mean_P2)-1;
            if app.px_cal ~=0
                app.sl_data.sarcomere_length_fft(box_no) = ...
                    size(app.sig,2)*app.px_cal/F_p(locs(max_ix));
            else
                app.sl_data.sarcomere_length_fft(box_no) = 0;
            end
            
            UpdateDisplay(app)
        end

        function SLAutoCorr(app,ct)
            x = app.sig(ct,:);
            [app.X_acf(ct,:),app.lags(ct,:)] = autocorr(x,NumLags=round(numel(x)/2));
            if ct == inf
                draw_graph = 1;
            else
                draw_graph = 0;
            end
        end

        function SLFFT(app,ct)
            x = app.sig(ct,:);
            X = fft(x);
            L = numel(X);
            app.P2(ct,:) = abs(X/L);
        end


        function UpdateTable(app)

            for i = 1:numel(app.sl_data.sarcomere_length_fft)
                s.box_no{i,1} = sprintf('%i',i);
                s.fft_sl(i,1) = app.sl_data.sarcomere_length_fft(i);
                s.acf_sl(i,1) = app.sl_data.sarcomere_length_acf(i);
            end

            s.box_no{end+1,1} = 'Average';
            s.fft_sl(end+1,1) = mean(app.sl_data.sarcomere_length_fft);
            s.acf_sl(end+1,1) = mean(app.sl_data.sarcomere_length_acf);

            t = struct2table(s);

            app.UITable.Data = t;


        end

        function UpdateDisplay(app)
            row_no = app.ROIRowSelectSpinner.Value;
            hold(app.px_intensity,"on")
            m = app.ROIRowSelectSpinner.Limits(2);
            cm = lines(m);
            for j = 1 : m
                plot(app.px_intensity,app.roi_box(j,:), ...
                    'LineStyle',':',"LineWidth",0.25,'Color',cm(j,:));
            end

            plot(app.px_intensity,app.roi_box(row_no,:), ...
                'LineStyle','-',"LineWidth",2,'Color',cm(row_no,:));
            hold(app.calculation_axis,"on")
            plot(app.calculation_axis, app.sig(row_no,:),'Color',cm(row_no,:),"LineWidth",2)
            xlim(app.px_intensity, [1 numel(app.profile(row_no,:))])
            xlim(app.calculation_axis, [1 numel(app.profile(row_no,:))])

            hold(app.fft_axis,"on")
            F_p = 0:numel(app.mean_P2)-1;
            [peaks, locs] = findpeaks(app.mean_P2);
            [max_mag,max_ix] = max(peaks);
            fft_ix = (1:numel(app.mean_P2)-1)/(numel(app.mean_P2));
            fft_ix = [0 fft_ix];
            plot(app.fft_axis,fft_ix,app.mean_P2,'Color','b','LineWidth',2)
            plot(app.fft_axis,fft_ix(locs(max_ix)),app.mean_P2(locs(max_ix)),'pentagram', ...
                'MarkerSize',15,"Color",'k','MarkerFaceColor','k')
            xlim(app.fft_axis,[0 fft_ix(end)])
            ylim(app.fft_axis,[0 max(max(app.mean_P2))+1])
            t = sprintf('Peak is at %.3f px^{-1}',fft_ix(locs(max_ix)));
            text(app.fft_axis,fft_ix(locs(max_ix))+0.25,max(peaks),t)

            plot(app.ac_axis,app.lags(1,:),app.mean_X_acf,'o','LineWidth',2,'color','r')
            hold(app.ac_axis,"on")
            plot(app.ac_axis,app.lags(1,:),app.y_fit,'color','k','LineWidth',1.5)
            if ~isempty(app.wavelength)
                t1 = sprintf('Wavelength  = %.3f px',app.wavelength);
                t2 = sprintf('R-squared = %.3f', app.r_squared);
                text(app.ac_axis,round(0.7*numel(app.mean_X_acf))-10,0.6,t1)
                text(app.ac_axis,round(0.7*numel(app.mean_X_acf))-10,0.8,t2)
            end
            %             xlim(app.ac_axis,[0 round(numel(x)/2)])
            ylim(app.ac_axis,[-1 1])

        end
        
        
        function LiveROIFeed(app)
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            colormap(app.SLMeasureUIFigure, 'gray');
            addpath(genpath('utilities'))
            movegui(app.SLMeasureUIFigure,'center')
            addpath(genpath('camera'))
            
            ipath = ['-I' fullfile(cd,'camera')];
            f = fullfile(cd,'camera','mexToupcam.cpp');
            lib = fullfile(cd,'camera','toupcam.lib');
%             mex mexToupcam.cpp -ltoupcam
            
            mex('-v','-R2017b',ipath,f,lib)

            global bStop;
            bStop = 0;
            [devN, devList] = mexToupcam;
            
            if devN == 0
                app.DeviceList.Items = {'No Device'};
            else
                app.Devices = devList;
                
                for i = 1 : numel(devList)
                    
                    app.DeviceList.Items{i} = devList{i};
                    
                end
                
            end
            
            app.ResolutionDropDown.Value


        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            filterspec = {'*.tif;*.png','All Image Files'};
            [file_string,path_string] = uigetfile2(filterspec);
            if (path_string~=0)
                ClearDisplay(app)
                app.sl_data = [];
                app.UITable.Data = [];
                app.sl_data.image_file_string = fullfile(path_string,file_string);
                im = imread(app.sl_data.image_file_string);
                if (ndims(im)==3)
                    im = rgb2gray(im);
                end
                center_image_with_preserved_aspect_ratio( ...
                    im, ...
                    app.image_axis);
                app.sl_data.image_file = im;
            end
        end

        % Button pushed function: NewBoxButton
        function NewBoxButtonPushed(app, event)

            if (~isfield(app.sl_data,'box_handle'))
                n=1;
                app.sl_data.box_handle(n) = drawrectangle(app.image_axis);
                p = app.sl_data.box_handle(n).Position;
                app.sl_data.old_width = p(3);
                app.sl_data.old_height = p(4);
            else
                n = 1 + numel(app.sl_data.box_handle);
                p = app.sl_data.box_handle(n-1).Position;

                app.sl_data.box_handle(n) = images.roi.Rectangle(app.image_axis, ...
                    'Position',p + [150,0,0,0]);
                for i=1:(n-1)
                    app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                end

            end

            app.sl_data.box_handle(n).Color = [0 1 0];
            app.sl_data.box_handle(n).FaceAlpha = 0;
            for i=1:(n-1)
                app.sl_data.box_handle(i).Color = [1 0 0];
            end

            app.sl_data.box_label(n) = text(app.image_axis, ...
                p(1)+p(3)+20,p(2)-30,sprintf('%.0f',n),'FontWeight',"bold","FontSize",18,"Color",'k');

            app.roi_pos = get(app.roi_rec, 'Position');
            app.px_cal = app.MicroscopeCalibrationumpxDropDown.Value;

            for i=1:n
                control_strings{i}=sprintf('%.0f',i);
            end

            app.BoxSelectionDropDown.Items = control_strings;
            app.BoxSelectionDropDown.Value = control_strings{n};

            SarcomereLength(app)
            UpdateTable(app);
            addlistener(app.sl_data.box_handle(n),"MovingROI",@(src,evt) UpdateSL(evt));

            function UpdateSL(evt)
                box_no = str2num(app.BoxSelectionDropDown.Value);
                app.ROIHeightpxEditField.Value = app.sl_data.box_handle(box_no).Position(3);
                app.ROIWidthpxEditField.Value = app.sl_data.box_handle(box_no).Position(4);
                cla(app.px_intensity)
                cla(app.calculation_axis)
                cla(app.fft_axis)
                cla(app.ac_axis)
                SarcomereLength(app)
                UpdateTable(app);
            end

        end

        % Value changed function: MicroscopeCalibrationumpxDropDown
        function MicroscopeCalibrationumpxEditFieldValueChanged(app, event)
            app.px_cal = str2num(app.MicroscopeCalibrationumpxDropDown.Value);
            cur_box = app.BoxSelectionDropDown.Value;
            if isfield(app.sl_data,'box_handle')
                for i = 1 : numel(app.sl_data.box_handle)
                    app.BoxSelectionDropDown.Value = num2str(i);
                    SarcomereLength(app)
                    UpdateTable(app)
                end
                app.BoxSelectionDropDown.Value = cur_box;
                app.BoxSelectionDropDownValueChanged;
            end
        end

        % Value changed function: BoxSelectionDropDown
        function BoxSelectionDropDownValueChanged(app, event)
            selected_box = str2num(app.BoxSelectionDropDown.Value);
            control_strings = app.BoxSelectionDropDown.Items;

            n = numel(control_strings);
            for i=1:n
                if (i~=selected_box)
                    app.sl_data.box_handle(i).Color = [1 0 0];
                    app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                else
                    app.sl_data.box_handle(i).Color = [0 1 0];
                    app.sl_data.box_handle(i).InteractionsAllowed = 'all';
                end
            end
            SarcomereLength(app)
            UpdateTable(app)
        end

        % Value changed function: ROIRowSelectSpinner
        function ROIRowSelectSpinnerValueChanged(app, event)
            value = app.ROIRowSelectSpinner.Value;
            cla(app.ac_axis)
            cla(app.fft_axis)
            cla(app.calculation_axis)
            cla(app.px_intensity)
            cla(app.ac_axis)
            UpdateDisplay(app)
        end

        % Button pushed function: ExportSLTableButton
        function ExportSLTableButtonPushed(app, event)
            [file_string,path_string] = uiputfile2( ...
                {'*.xlsx','Excel file'},'Select file name for SL Table');
            output_file = fullfile(path_string,file_string);

            try
                delete(output_file);
            end

            writetable(app.UITable.Data,output_file,'Sheet','Summary')
        end

        % Menu selected function: SaveMeasurementMenu
        function SaveMeasurementMenuSelected(app, event)
            if ~isfield(app.sl_data,'box_handle')
                warndlg('The measurement boxes are not available.')
                return
            end
            [file_string,path_string] = uiputfile2( ...
                {'*.sl','SL measurement file'},'Select file to save measurement');

            for i=1:numel(app.sl_data.box_handle)
                app.sl_data.box_position(i,:) = app.sl_data.box_handle(i).Position;
            end
            save_data.image_file_string = app.sl_data.image_file_string;
            save_data.im_data = app.sl_data.image_file;
            save_data.px_cal = app.px_cal;
            save_data.box_position = app.sl_data.box_position;

            if (path_string~=0)
                save(fullfile(path_string,file_string),'save_data');
            end

        end

        % Menu selected function: LoadMeasurementMenu
        function LoadMeasurementMenuSelected(app, event)
            [file_string,path_string] = uigetfile2( ...
                {'*.sl','SL measurement file'},'Select file to load measurement');
            if (path_string~=0)
                ClearDisplay(app)
                if (isfield(app.sl_data,'box_handle'))
                    n = numel(app.sl_data.box_handle);
                    for i=1:n
                        delete(app.sl_data.box_handle(i));
                    end
                end

                temp = load(fullfile(path_string,file_string),'-mat','save_data');
                save_data = temp.save_data;

                app.sl_data = [];
                app.sl_data.image_file_string = save_data.image_file_string;
                app.sl_data.image_file = save_data.im_data;
                app.px_cal = save_data.px_cal;
                if app.px_cal == str2num(app.MicroscopeCalibrationumpxDropDown.Value)
                   app.px_cal = str2num(app.MicroscopeCalibrationumpxDropDown.Value);
                end
                
                center_image_with_preserved_aspect_ratio( ...
                    app.sl_data.image_file, ...
                    app.image_axis);
                control_strings = [];
                for i=1:size(save_data.box_position,1)
                    app.sl_data.box_handle(i) = images.roi.Rectangle(app.image_axis, ...
                        'Position',save_data.box_position(i,:));
                    control_strings{i} = sprintf('%.0f',i);
                end
                app.BoxSelectionDropDown.Items = control_strings;
                app.BoxSelectionDropDown.Value = control_strings{1};
                for i=1:size(save_data.box_position,1)
                    app.sl_data.box_handle(i).FaceAlpha = 0;
                    if (i~=1)
                        app.sl_data.box_handle(i).Color = [1 0 0];
                        app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                    else
                        app.sl_data.box_handle(i).Color = [0 1 0];
                        app.sl_data.box_handle(i).InteractionsAllowed = 'all';
                    end

                    p = app.sl_data.box_handle(i).Position;
                    app.sl_data.box_label(i) = text(p(1)+p(3),p(2)-30,sprintf('%.0f',i), ...
                        'Parent',app.image_axis,'FontWeight',"bold","FontSize",18,"Color",'k');
                    app.sl_data.old_width = p(3);
                    app.sl_data.old_height = p(4);

                    i=i;
                    addlistener(app.sl_data.box_handle(i),"MovingROI",@(src,evt) UpdateSL2(evt));
                end
                for i = numel(app.sl_data.box_handle) :-1: 1
                    app.BoxSelectionDropDown.Value = num2str(i);
                    SarcomereLength(app)
                    UpdateDisplay(app)
                    UpdateTable(app);
                end
            end
            function UpdateSL2(evt)
                box_no = str2num(app.BoxSelectionDropDown.Value);
                app.ROIHeightpxEditField.Value = app.sl_data.box_handle(box_no).Position(3);
                app.ROIWidthpxEditField.Value = app.sl_data.box_handle(box_no).Position(4);
                cla(app.px_intensity)
                cla(app.calculation_axis)
                cla(app.fft_axis)
                cla(app.ac_axis)
                SarcomereLength(app)
                UpdateTable(app);
            end

        end

        % Callback function
        function RSquaredThresholdEditFieldValueChanged(app, event)
            value = app.RSquaredThresholdEditField.Value;
            cla(app.r_squared_axes);
            ct = app.ROIHeightpxEditField.Value;
            plot(app.r_squared_axes,1:ct,ones(1,ct)*app.RSquaredThresholdEditField.Value,':','Color','r')
            plot(app.r_squared_axes,1:ct,app.r_squared,'ks')

            
        end

        % Button pushed function: DeleteBoxButton
        function DeleteBoxButtonPushed(app, event)
            selected_box = str2num(app.BoxSelectionDropDown.Value);
            control_strings = app.BoxSelectionDropDown.Items;

            delete(app.sl_data.box_handle(selected_box))
            delete(app.sl_data.box_label(selected_box))
            
            app.sl_data.box_handle(selected_box) = [];
            app.sl_data.box_label(selected_box) = [];
            
            app.sl_data.sarcomere_length_fft(selected_box) = [];
            app.sl_data.sarcomere_length_acf(selected_box) = [];

            n = numel(control_strings) - 1;
            control_strings = {};
            for i=1:n
                control_strings{i} = sprintf('%.0f',i);
            end

            app.BoxSelectionDropDown.Items = control_strings;
            if selected_box == 1
                app.BoxSelectionDropDown.Value = num2str(selected_box);
            else
                app.BoxSelectionDropDown.Value = num2str(selected_box-1);
            end

            new_selected_box = str2num(app.BoxSelectionDropDown.Value);

            for i=1:n
                delete(app.sl_data.box_label(i));
                if (i~=new_selected_box)
                    app.sl_data.box_handle(i).Color = [1 0 0];
                    app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                else
                    app.sl_data.box_handle(i).Color = [0 1 0];
                    app.sl_data.box_handle(i).InteractionsAllowed = 'all';
                end
            end

            app.sl_data.box_label = [];
            for i = 1:n
                p = app.sl_data.box_handle(i).Position;
                app.sl_data.box_label(i) = text(app.image_axis, ...
                p(1)+p(3)+20,p(2)-30,sprintf('%.0f',i),'FontWeight',"bold","FontSize",18,"Color",'w');

                app.sl_data.old_width = p(3);
                app.sl_data.old_height = p(4);
            end
            cla(app.ac_axis)
            cla(app.fft_axis)
            SarcomereLength(app)
            UpdateDisplay(app)
            UpdateTable(app)
        end

        % Value changed function: StartLiveMeasurementButton
        function StartLiveMeasurementButtonValueChanged(app, event)
            global bStop
            value = app.StartLiveMeasurementButton.Value;
            app.LoadImageButton.Enable = 'off';
            selected_device = app.DeviceList.Value;
            
            resolution = app.ResolutionDropDown.Value;
            
            switch resolution
                case '1024 x 822'
                    nResolutionIndex = 0;
                    width = 1024;
                    height = 822;
                case '2048 x 1644'
                    nResolutionIndex = 0;
                    width = 2048;
                    height = 1644;
                case '4096 x 3288'
                    nResolutionIndex = 0;
                    width = 4096;
                    height = 3288;
            end
            
            bStop = 0;
            app.DeviceList.Enable = 'off';
            items = app.DeviceList.Items;
            index = strcmp(selected_device,items);
            nSpeed = 1;
            devList = app.Devices;
            [im, ~, ~, app.ToupcamData] = mexToupcam(nResolutionIndex, nSpeed, devList(index).id, index);
            app.live_image = zeros(height,width, 3);
            while ~isequal(bStop,2)
                for i = 1 : height
                    for j = 1 :width
                        app.live_image(i,j,1) = im(3*(j-1)+3,i);
                        app.live_image(i,j,2) = im(3*(j-1)+2,i);
                        app.live_image(i,j,3) = im(3*(j-1)+1,i);
                    end
                end
                hold(app.image_axis,'on')
                if (isfield(app.sl_data,'box_handle'))
                    for i=1:size(save_data.box_position,1)
                        app.sl_data.box_handle(i) = images.roi.Rectangle(app.image_axis, ...
                            'Position',save_data.box_position(i,:));
                        control_strings{i} = sprintf('%.0f',i);
                    end
                app.BoxSelectionDropDown.Items = control_strings;
                app.BoxSelectionDropDown.Value = control_strings{1};
                for i=1:size(save_data.box_position,1)
                    app.sl_data.box_handle(i).FaceAlpha = 0;
                    if (i~=1)
                        app.sl_data.box_handle(i).Color = [1 0 0];
                        app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                    else
                        app.sl_data.box_handle(i).Color = [0 1 0];
                        app.sl_data.box_handle(i).InteractionsAllowed = 'all';
                    end

                    p = app.sl_data.box_handle(i).Position;
                    app.sl_data.box_label(i) = text(p(1)+p(3),p(2)-30,sprintf('%.0f',i), ...
                        'Parent',app.image_axis,'FontWeight',"bold","FontSize",18,"Color",'k');
                    app.sl_data.old_width = p(3);
                    app.sl_data.old_height = p(4);

                    i=i;
                    addlistener(app.sl_data.box_handle(i),"MovingROI",@(src,evt) UpdateSL2(evt));
                end
                else
                    n = 1 + numel(app.sl_data.box_handle);
                    p = app.sl_data.box_handle(n-1).Position;

                    app.sl_data.box_handle(n) = images.roi.Rectangle(app.image_axis, ...
                        'Position',p + [150,0,0,0]);
                    for i=1:(n-1)
                        app.sl_data.box_handle(i).InteractionsAllowed = 'none';
                    end

                end
                center_image_with_preserved_aspect_ratio( ...
                    uint8(app.live_image), ...
                    app.image_axis);
                drawnow;
                if bStop==1
                    uiwait;
                end
            end
            mexToupcam(0,0);
            clear im;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SLMeasureUIFigure = uifigure('Visible', 'off');
            app.SLMeasureUIFigure.Name = 'SLMeasure';
            app.FileMenu = uimenu(app.SLMeasureUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMeasurementMenu = uimenu(app.FileMenu);
            app.SaveMeasurementMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMeasurementMenuSelected, true);
            app.SaveMeasurementMenu.Separator = 'on';
            app.SaveMeasurementMenu.Text = 'Save Measurement';
            app.LoadMeasurementMenu = uimenu(app.FileMenu);
            app.LoadMeasurementMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMeasurementMenuSelected, true);
            app.LoadMeasurementMenu.Separator = 'on';
            app.LoadMeasurementMenu.Text = 'Load Measurement';
            app.BrightfieldPanel = uipanel(app.SLMeasureUIFigure);
            app.BrightfieldPanel.Title = 'Brightfield Panel';
            app.image_axis = uiaxes(app.BrightfieldPanel);
            title(app.image_axis, 'Brightfield Image')
            app.image_axis.XTick = [];
            app.image_axis.YTick = [];
            app.inset_axis = uiaxes(app.BrightfieldPanel);
            title(app.inset_axis, 'Region of Interest (ROI)')
            app.inset_axis.XTick = [];
            app.inset_axis.YTick = [];
            app.px_intensity = uiaxes(app.BrightfieldPanel);
            title(app.px_intensity, 'Intensity Profiles')
            xlabel(app.px_intensity, 'ROI Column')
            ylabel(app.px_intensity, 'Optical Intensity (A.U)')
            zlabel(app.px_intensity, 'Z')
            app.calculation_axis = uiaxes(app.BrightfieldPanel);
            title(app.calculation_axis, 'Derivative of the Intensity Profile')
            xlabel(app.calculation_axis, 'ROI Index')
            ylabel(app.calculation_axis, 'd(profile)/dx')
            zlabel(app.calculation_axis, 'Z')
            app.ROIHeightpxEditFieldLabel = uilabel(app.BrightfieldPanel);
            app.ROIHeightpxEditFieldLabel.Text = 'ROI Height (px)';
            app.ROIHeightpxEditField = uieditfield(app.BrightfieldPanel, 'numeric');
            app.ROIWidthpxEditFieldLabel = uilabel(app.BrightfieldPanel);
            app.ROIWidthpxEditFieldLabel.Text = 'ROI Width (px)';
            app.ROIWidthpxEditField = uieditfield(app.BrightfieldPanel, 'numeric');
            app.ROIRowSelectSpinnerLabel = uilabel(app.BrightfieldPanel);
            app.ROIRowSelectSpinnerLabel.Text = 'ROI Row Select';
            app.ROIRowSelectSpinner = uispinner(app.BrightfieldPanel);
            app.ROIRowSelectSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIRowSelectSpinnerValueChanged, true);
            app.ROIRowSelectSpinner.Value = 1;
            app.SarcomereLengthCalculationPanel = uipanel(app.SLMeasureUIFigure);
            app.SarcomereLengthCalculationPanel.Title = 'Sarcomere Length Calculation';
            app.fft_axis = uiaxes(app.SarcomereLengthCalculationPanel);
            title(app.fft_axis, 'FFT: Double-Sided Spectrum')
            xlabel(app.fft_axis, 'Pixels^{-1}')
            ylabel(app.fft_axis, 'Amplitude (A.U.)')
            zlabel(app.fft_axis, 'Z')
            app.ac_axis = uiaxes(app.SarcomereLengthCalculationPanel);
            title(app.ac_axis, 'ACF')
            xlabel(app.ac_axis, 'Pixels')
            ylabel(app.ac_axis, 'Amplitude (A.U.)')
            zlabel(app.ac_axis, 'Z')
            app.UITable = uitable(app.SarcomereLengthCalculationPanel);
            app.UITable.ColumnName = {'Box No'; 'FFT SL'; 'ACF SL'};
            app.UITable.RowName = {};
            app.ExportSLTableButton = uibutton(app.SarcomereLengthCalculationPanel, 'push');
            app.ExportSLTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSLTableButtonPushed, true);
            app.ExportSLTableButton.Text = 'Export SL Table';
            app.LoadImageButton = uibutton(app.SLMeasureUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.NewBoxButton = uibutton(app.SLMeasureUIFigure, 'push');
            app.NewBoxButton.ButtonPushedFcn = createCallbackFcn(app, @NewBoxButtonPushed, true);
            app.NewBoxButton.Text = 'New Box';
            app.BoxSelectionDropDownLabel = uilabel(app.SLMeasureUIFigure);
            app.BoxSelectionDropDownLabel.Text = 'Box Selection';
            app.BoxSelectionDropDown = uidropdown(app.SLMeasureUIFigure);
            app.BoxSelectionDropDown.Items = {};
            app.BoxSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @BoxSelectionDropDownValueChanged, true);
            app.BoxSelectionDropDown.Placeholder = 'No Data';
            app.BoxSelectionDropDown.Value = {};
            app.DeleteBoxButton = uibutton(app.SLMeasureUIFigure, 'push');
            app.DeleteBoxButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBoxButtonPushed, true);
            app.DeleteBoxButton.Text = 'Delete Box';
            app.MicroscopeCalibrationumpxDropDownLabel = uilabel(app.SLMeasureUIFigure);
            app.MicroscopeCalibrationumpxDropDownLabel.Text = 'Microscope Calibration (um/px)';
            app.MicroscopeCalibrationumpxDropDown = uidropdown(app.SLMeasureUIFigure);
            app.MicroscopeCalibrationumpxDropDown.Items = {'0.2866', '1.4503', 'Add. Calibration'};
            app.MicroscopeCalibrationumpxDropDown.ValueChangedFcn = createCallbackFcn(app, @MicroscopeCalibrationumpxEditFieldValueChanged, true);
            app.MicroscopeCalibrationumpxDropDown.Placeholder = 'Select cal.';
            app.MicroscopeCalibrationumpxDropDown.Value = '0.2866';
            app.CameraPanel = uipanel(app.SLMeasureUIFigure);
            app.CameraPanel.Title = 'Camera Panel';
            app.DeviceListPanel = uipanel(app.CameraPanel);
            app.DeviceListPanel.Title = 'Choose Device';
            app.DeviceListPanel.Tag = 'uipanel_device';
            app.StartLiveMeasurementButton = uibutton(app.DeviceListPanel, 'state');
            app.StartLiveMeasurementButton.ValueChangedFcn = createCallbackFcn(app, @StartLiveMeasurementButtonValueChanged, true);
            app.StartLiveMeasurementButton.Text = 'Start Live Measurement';
            app.DeviceList = uilistbox(app.CameraPanel);
            app.DeviceList.Items = {};
            app.DeviceList.Tag = 'listbox_device';
            app.DeviceList.Value = {};
            app.SnapshotRecordPanel = uipanel(app.CameraPanel);
            app.SnapshotRecordPanel.Title = 'Snapshot and Record';
            app.SnapshotRecordPanel.Tag = 'uipanel_device';
            app.ExposurePanel = uipanel(app.CameraPanel);
            app.ExposurePanel.Title = 'Exposure';
            app.ExposurePanel.Tag = 'uipanel_device';
            app.WhiteBalancePanel = uipanel(app.CameraPanel);
            app.WhiteBalancePanel.Title = 'White Balance';
            app.WhiteBalancePanel.Tag = 'uipanel_device';
            app.ColorAdjustmentPanel = uipanel(app.CameraPanel);
            app.ColorAdjustmentPanel.Title = 'Color Adjustment';
            app.ColorAdjustmentPanel.Tag = 'uipanel_device';
            app.ResolutionPanel = uipanel(app.CameraPanel);
            app.ResolutionPanel.Title = 'Resolution';
            app.ResolutionPanel.Tag = 'uipanel_device';
            app.ResolutionDropDown = uidropdown(app.ResolutionPanel);
            app.ResolutionDropDown.Items = {'4096 x 3288', '2048 x 1644', '1024 x 822'};
            app.ResolutionDropDown.Placeholder = 'Height x Width';
            app.ResolutionDropDown.Value = '1024 x 822';
            % Show the figure after all components are created
            app.SLMeasureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLMeasure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLMeasureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLMeasureUIFigure)
        end
    end
end