classdef demomatlab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        demomat_gui             matlab.ui.Figure
        uipanel7                matlab.ui.container.Panel
        pushbutton_defaults     matlab.ui.control.Button
        slider_gamma            matlab.ui.control.Slider
        text_gamma              matlab.ui.control.Label
        text18                  matlab.ui.control.Label
        slider_contrast         matlab.ui.control.Slider
        text_contrast           matlab.ui.control.Label
        text16                  matlab.ui.control.Label
        slider_brightness       matlab.ui.control.Slider
        text_brightness         matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        slider_saturation       matlab.ui.control.Slider
        text_saturation         matlab.ui.control.Label
        text12                  matlab.ui.control.Label
        slider_hue              matlab.ui.control.Slider
        text_hue                matlab.ui.control.Label
        text10                  matlab.ui.control.Label
        uipanel6                matlab.ui.container.Panel
        pushbutton_snap         matlab.ui.control.Button
        exposure                matlab.ui.container.Panel
        text_exposure_time      matlab.ui.control.Label
        checkbox_auto_exposure  matlab.ui.control.CheckBox
        exposure_time           matlab.ui.control.Label
        slider_exposure_time    matlab.ui.control.Slider
        uipanel4                matlab.ui.container.Panel
        pushbutton_wbdefaults   matlab.ui.control.Button
        text_tint               matlab.ui.control.Label
        text_temp               matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        text3                   matlab.ui.control.Label
        slider_tint             matlab.ui.control.Slider
        slider_temp             matlab.ui.control.Slider
        pushbutton_awbOnePush   matlab.ui.control.Button
        uipanel_device          matlab.ui.container.Panel
        listbox_device          matlab.ui.control.ListBox
        axes_vedio              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function demomatlab_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.demomat_gui, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;

            guidata(hObject, handles);
            cla;
            ipath = ['-I' fullfile(cd,'../camera')];
            f = fullfile(cd,'../camera','mexToupcam.cpp');
            lib = fullfile(cd,'../camera','toupcam.lib');
            %             mex mexToupcam.cpp -ltoupcam
            
            mex('-v','-R2017b',ipath,f,lib)
            global bStop;
            bStop=0;
            [devN,devList]=mexToupcam;
            if (devN==0)
                set(handles.listbox_device,'string','No Device');
            else
                setappdata(hObject,'Devices',devList);
                set(handles.listbox_device,'string',devList.name);
            end
        end

        % Value changed function: checkbox_auto_exposure
        function checkbox_auto_exposure_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1; %#ok<*NASGU>
            [a,time]=mexToupcam(2,0);
            time=roundn(time/1000,-2);
            if (a==0)
                set(hObject,'Value',0);
                set(handles.slider_exposure_time,'enable','on');
                set(handles.text_exposure_time,'Visible','on');
                set(handles.slider_exposure_time,'Value',time);
                set(handles.text_exposure_time,'String',num2str(time));
            else
                set(hObject,'Value',1);
                set(handles.slider_exposure_time,'enable','off');
                set(handles.text_exposure_time,'Visible','off');
            end
            bStop = 0;
            uiresume;
        end

        % Close request function: demomat_gui
        function demomat_gui_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 2;
            delete(hObject);
        end

        % Value changed function: listbox_device
        function listbox_device_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            global bStop;
            bStop=0;
            set(hObject,'enable','off');
            index=get(hObject,'value');
            nResolutionIndex=2;
            nSpeed=1;
            devList=getappdata(gcf,'Devices');
            [im, width, height, ToupcamData]=mexToupcam(nResolutionIndex, nSpeed, devList(index).id, index);
            setappdata(hObject,'ToupcamData',ToupcamData);
            set(handles.slider_exposure_time,'enable','off');
            set(handles.pushbutton_snap,'enable','on');
            set(handles.pushbutton_awbOnePush,'enable','on');
            set(handles.pushbutton_wbdefaults,'enable','on');
            set(handles.pushbutton_defaults,'enable','on');
            set(handles.slider_temp,'Enable','on');
            set(handles.slider_temp,'Value',ToupcamData.TOUPCAM_TEMP_DEF);
            set(handles.slider_tint,'Enable','on');
            set(handles.slider_tint,'Value',ToupcamData.TOUPCAM_TINT_DEF);
            set(handles.text_temp,'Visible','on');
            set(handles.text_temp,'String',num2str(ToupcamData.TOUPCAM_TEMP_DEF));
            set(handles.text_tint,'Visible','on');
            set(handles.text_tint,'String',num2str(ToupcamData.TOUPCAM_TINT_DEF));
            set(handles.text_hue,'Visible','on');
            set(handles.text_hue,'String',num2str(ToupcamData.TOUPCAM_HUE_DEF));
            set(handles.slider_hue,'Enable','on');
            set(handles.slider_hue,'Value',ToupcamData.TOUPCAM_HUE_DEF);
            set(handles.text_saturation,'Visible','on');
            set(handles.text_saturation,'String',num2str(ToupcamData.TOUPCAM_SATURATION_DEF));
            set(handles.slider_saturation,'Enable','on');
            set(handles.slider_saturation,'Value',ToupcamData.TOUPCAM_SATURATION_DEF);
            set(handles.text_brightness,'Visible','on');
            set(handles.text_brightness,'String',num2str(ToupcamData.TOUPCAM_BRIGHTNESS_DEF));
            set(handles.slider_brightness,'Enable','on');
            set(handles.slider_brightness,'Value',ToupcamData.TOUPCAM_BRIGHTNESS_DEF);
            set(handles.text_contrast,'Visible','on');
            set(handles.text_contrast,'String',num2str(ToupcamData.TOUPCAM_CONTRAST_DEF));
            set(handles.slider_contrast,'Enable','on');
            set(handles.slider_contrast,'Value',ToupcamData.TOUPCAM_CONTRAST_DEF);
            set(handles.text_gamma,'Visible','on');
            set(handles.text_gamma,'String',num2str(ToupcamData.TOUPCAM_GAMMA_DEF));
            set(handles.slider_gamma,'Enable','on');
            set(handles.slider_gamma,'Value',ToupcamData.TOUPCAM_GAMMA_DEF);
            img=zeros(height,width, 3);
            warning('off');
            axes(handles.axes_vedio);
            while ~isequal(bStop,2)
                tic
                for i=1:height
                    for j=1:width
                        img(i,j,1)=im(3*(j-1)+3,i);
%                         img(i,j,2)=im(3*(j-1)+2,i);
%                         img(i,j,3)=im(3*(j-1)+1,i);
                    end
                end
                imshow(uint8(img));
                drawnow;
                toc
                if bStop==1
                    uiwait;
                end
            end
            mexToupcam(0,0);
            clear im;

        end

        % Button pushed function: pushbutton_awbOnePush
        function pushbutton_awbOnePush_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            [temp,tint]=mexToupcam(4,0);
            set(handles.text_temp,'String',num2str(temp));
            set(handles.text_tint,'String',num2str(tint));
            set(handles.slider_temp,'Value',temp);
            set(handles.slider_tint,'Value',tint);
            bStop = 0;
            uiresume;
        end

        % Button pushed function: pushbutton_defaults
        function pushbutton_defaults_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            ToupcamData = getappdata(handles.listbox_device,'ToupcamData');
            mexToupcam(13,0);
            set(handles.text_hue,'String', num2str(ToupcamData.TOUPCAM_HUE_DEF));
            set(handles.slider_hue,'Value', ToupcamData.TOUPCAM_HUE_DEF);
            set(handles.text_saturation,'String', num2str(ToupcamData.TOUPCAM_SATURATION_DEF));
            set(handles.slider_saturation,'Value', ToupcamData.TOUPCAM_SATURATION_DEF);
            set(handles.text_brightness,'String', num2str(ToupcamData.TOUPCAM_BRIGHTNESS_DEF));
            set(handles.slider_brightness,'Value', ToupcamData.TOUPCAM_BRIGHTNESS_DEF);
            set(handles.text_contrast,'String', num2str(ToupcamData.TOUPCAM_CONTRAST_DEF));
            set(handles.slider_contrast,'Value', ToupcamData.TOUPCAM_CONTRAST_DEF);
            set(handles.text_gamma,'String', num2str(ToupcamData.TOUPCAM_GAMMA_DEF));
            set(handles.slider_gamma,'Value', ToupcamData.TOUPCAM_GAMMA_DEF);
            bStop = 0;
            uiresume;
        end

        % Button pushed function: pushbutton_snap
        function pushbutton_snap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            mexToupcam(1,0);
        end

        % Button pushed function: pushbutton_wbdefaults
        function pushbutton_wbdefaults_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            mexToupcam(12,0);
            ToupcamData = getappdata(handles.listbox_device,'ToupcamData');
            set(handles.text_temp,'String', num2str(ToupcamData.TOUPCAM_TEMP_DEF));
            set(handles.slider_temp,'Value', ToupcamData.TOUPCAM_TEMP_DEF);
            set(handles.text_tint,'String', num2str(ToupcamData.TOUPCAM_TINT_DEF));
            set(handles.slider_tint,'Value', ToupcamData.TOUPCAM_TINT_DEF);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_brightness
        function slider_brightness_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=round(get(hObject,'Value'));
            set(handles.text_brightness,'String',num2str(val));
            mexToupcam(9,val);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_contrast
        function slider_contrast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=round(get(hObject,'Value'));
            set(handles.text_contrast,'String',num2str(val));
            mexToupcam(10,val);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_exposure_time
        function slider_exposure_time_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=get(hObject,'Value');
            set(handles.text_exposure_time,'Visible','on');
            set(handles.text_exposure_time,'String',num2str(roundn(val,-2)));
            mexToupcam(3,val*1000);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_gamma
        function slider_gamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=round(get(hObject,'Value'));
            set(handles.text_gamma,'String',num2str(val));
            mexToupcam(11,val);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_hue
        function slider_hue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=round(get(hObject,'Value'));
            set(handles.text_hue,'String',num2str(val));
            mexToupcam(7,val);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_saturation
        function slider_saturation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=round(get(hObject,'Value'));
            set(handles.text_saturation,'String',num2str(val));
            mexToupcam(8,val);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_temp
        function slider_temp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=round(get(hObject,'Value'));
            set(handles.text_temp,'String',num2str(val));
            mexToupcam(5,val);
            bStop = 0;
            uiresume;
        end

        % Value changed function: slider_tint
        function slider_tint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global bStop;
            bStop = 1;
            val=round(get(hObject,'Value'));
            set(handles.text_tint,'String',num2str(val));
            mexToupcam(6,val);
            bStop = 0;
            uiresume;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.demomat_gui = uifigure('Visible', 'off');
            app.demomat_gui.Name = 'Toupcam Matlab Demo';
            app.demomat_gui.CloseRequestFcn = createCallbackFcn(app, @demomat_gui_CloseRequestFcn, true);
            app.demomat_gui.Tag = 'demomat_gui';
            app.axes_vedio = uiaxes(app.demomat_gui);
            app.axes_vedio.Visible = 'off';
            app.axes_vedio.Tag = 'axes_vedio';
            app.uipanel_device = uipanel(app.demomat_gui);
            app.uipanel_device.Title = 'Choose Device';
            app.uipanel_device.Tag = 'uipanel_device';
            app.listbox_device = uilistbox(app.uipanel_device);
            app.listbox_device.Items = {};
            app.listbox_device.ValueChangedFcn = createCallbackFcn(app, @listbox_device_Callback, true);
            app.listbox_device.Tag = 'listbox_device';
            app.listbox_device.Value = {};
            app.uipanel4 = uipanel(app.demomat_gui);
            app.uipanel4.Title = 'White Balance';
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton_awbOnePush = uibutton(app.uipanel4, 'push');
            app.pushbutton_awbOnePush.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_awbOnePush_Callback, true);
            app.pushbutton_awbOnePush.Tag = 'pushbutton_awbOnePush';
            app.pushbutton_awbOnePush.Enable = 'off';
            app.pushbutton_awbOnePush.Text = 'White Balance';
            app.slider_temp = uislider(app.uipanel4);
            app.slider_temp.Limits = [2000 15000];
            app.slider_temp.MajorTicks = [];
            app.slider_temp.ValueChangedFcn = createCallbackFcn(app, @slider_temp_Callback, true);
            app.slider_temp.MinorTicks = [];
            app.slider_temp.Tag = 'slider_temp';
            app.slider_temp.Enable = 'off';
            app.slider_temp.Value = 6503;
            app.slider_tint = uislider(app.uipanel4);
            app.slider_tint.Limits = [200 2500];
            app.slider_tint.MajorTicks = [];
            app.slider_tint.ValueChangedFcn = createCallbackFcn(app, @slider_tint_Callback, true);
            app.slider_tint.MinorTicks = [];
            app.slider_tint.Tag = 'slider_tint';
            app.slider_tint.Enable = 'off';
            app.slider_tint.Value = 1000;
            app.text3 = uilabel(app.uipanel4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Temp:';
            app.text4 = uilabel(app.uipanel4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tint:';
            app.text_temp = uilabel(app.uipanel4);
            app.text_temp.Tag = 'text_temp';
            app.text_temp.Visible = 'off';
            app.text_temp.Text = '2000';
            app.text_tint = uilabel(app.uipanel4);
            app.text_tint.Tag = 'text_tint';
            app.text_tint.Visible = 'off';
            app.text_tint.Text = '200';
            app.pushbutton_wbdefaults = uibutton(app.uipanel4, 'push');
            app.pushbutton_wbdefaults.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_wbdefaults_Callback, true);
            app.pushbutton_wbdefaults.Tag = 'pushbutton_wbdefaults';
            app.pushbutton_wbdefaults.Enable = 'off';
            app.pushbutton_wbdefaults.Text = 'Defaults';
            app.exposure = uipanel(app.demomat_gui);
            app.exposure.Title = 'Exposure';
            app.exposure.Tag = 'exposure';
            app.slider_exposure_time = uislider(app.exposure);
            app.slider_exposure_time.Limits = [0.244 2000];
            app.slider_exposure_time.MajorTicks = [];
            app.slider_exposure_time.ValueChangedFcn = createCallbackFcn(app, @slider_exposure_time_Callback, true);
            app.slider_exposure_time.MinorTicks = [];
            app.slider_exposure_time.Tag = 'slider_exposure_time';
            app.slider_exposure_time.Enable = 'off';
            app.slider_exposure_time.Value = 0.244;
            app.exposure_time = uilabel(app.exposure);
            app.exposure_time.Tag = 'exposure_time';
            app.exposure_time.Text = 'Exposure Time:';
            app.checkbox_auto_exposure = uicheckbox(app.exposure);
            app.checkbox_auto_exposure.ValueChangedFcn = createCallbackFcn(app, @checkbox_auto_exposure_Callback, true);
            app.checkbox_auto_exposure.Tag = 'checkbox_auto_exposure';
            app.checkbox_auto_exposure.Text = 'Auto exposure';
            app.checkbox_auto_exposure.Value = true;
            app.text_exposure_time = uilabel(app.exposure);
            app.text_exposure_time.Tag = 'text_exposure_time';
            app.text_exposure_time.Visible = 'off';
            app.text_exposure_time.Text = '0';
            app.uipanel6 = uipanel(app.demomat_gui);
            app.uipanel6.Title = 'Snap';
            app.uipanel6.Tag = 'uipanel6';
            app.pushbutton_snap = uibutton(app.uipanel6, 'push');
            app.pushbutton_snap.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_snap_Callback, true);
            app.pushbutton_snap.Tag = 'pushbutton_snap';
            app.pushbutton_snap.Enable = 'off';
            app.pushbutton_snap.Text = 'Snap';
            app.uipanel7 = uipanel(app.demomat_gui);
            app.uipanel7.Title = 'Color Adjustment';
            app.uipanel7.Tag = 'uipanel7';
            app.text10 = uilabel(app.uipanel7);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Hue';
            app.text_hue = uilabel(app.uipanel7);
            app.text_hue.Tag = 'text_hue';
            app.text_hue.Visible = 'off';
            app.text_hue.Text = '0';
            app.slider_hue = uislider(app.uipanel7);
            app.slider_hue.Limits = [-180 180];
            app.slider_hue.MajorTicks = [];
            app.slider_hue.ValueChangedFcn = createCallbackFcn(app, @slider_hue_Callback, true);
            app.slider_hue.MinorTicks = [];
            app.slider_hue.Tag = 'slider_hue';
            app.slider_hue.Enable = 'off';
            app.text12 = uilabel(app.uipanel7);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Saturation';
            app.text_saturation = uilabel(app.uipanel7);
            app.text_saturation.Tag = 'text_saturation';
            app.text_saturation.Visible = 'off';
            app.text_saturation.Text = '128';
            app.slider_saturation = uislider(app.uipanel7);
            app.slider_saturation.Limits = [0 255];
            app.slider_saturation.MajorTicks = [];
            app.slider_saturation.ValueChangedFcn = createCallbackFcn(app, @slider_saturation_Callback, true);
            app.slider_saturation.MinorTicks = [];
            app.slider_saturation.Tag = 'slider_saturation';
            app.slider_saturation.Enable = 'off';
            app.slider_saturation.Value = 128;
            app.text14 = uilabel(app.uipanel7);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Brightness';
            app.text_brightness = uilabel(app.uipanel7);
            app.text_brightness.Tag = 'text_brightness';
            app.text_brightness.Visible = 'off';
            app.text_brightness.Text = '0';
            app.slider_brightness = uislider(app.uipanel7);
            app.slider_brightness.Limits = [-64 64];
            app.slider_brightness.MajorTicks = [];
            app.slider_brightness.ValueChangedFcn = createCallbackFcn(app, @slider_brightness_Callback, true);
            app.slider_brightness.MinorTicks = [];
            app.slider_brightness.Tag = 'slider_brightness';
            app.slider_brightness.Enable = 'off';
            app.text16 = uilabel(app.uipanel7);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Contrast';
            app.text_contrast = uilabel(app.uipanel7);
            app.text_contrast.Tag = 'text_contrast';
            app.text_contrast.Visible = 'off';
            app.text_contrast.Text = '0';
            app.slider_contrast = uislider(app.uipanel7);
            app.slider_contrast.Limits = [-100 100];
            app.slider_contrast.MajorTicks = [];
            app.slider_contrast.ValueChangedFcn = createCallbackFcn(app, @slider_contrast_Callback, true);
            app.slider_contrast.MinorTicks = [];
            app.slider_contrast.Tag = 'slider_contrast';
            app.slider_contrast.Enable = 'off';
            app.text18 = uilabel(app.uipanel7);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Gamma';
            app.text_gamma = uilabel(app.uipanel7);
            app.text_gamma.Tag = 'text_gamma';
            app.text_gamma.Visible = 'off';
            app.text_gamma.Text = '100';
            app.slider_gamma = uislider(app.uipanel7);
            app.slider_gamma.Limits = [20 180];
            app.slider_gamma.MajorTicks = [];
            app.slider_gamma.ValueChangedFcn = createCallbackFcn(app, @slider_gamma_Callback, true);
            app.slider_gamma.MinorTicks = [];
            app.slider_gamma.Tag = 'slider_gamma';
            app.slider_gamma.Enable = 'off';
            app.slider_gamma.Value = 100;
            app.pushbutton_defaults = uibutton(app.uipanel7, 'push');
            app.pushbutton_defaults.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_defaults_Callback, true);
            app.pushbutton_defaults.Tag = 'pushbutton_defaults';
            app.pushbutton_defaults.Enable = 'off';
            app.pushbutton_defaults.Text = 'Defaults';
            % Show the figure after all components are created
            app.demomat_gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demomatlab_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.demomat_gui)

                % Execute the startup function
                runStartupFcn(app, @(app)demomatlab_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.demomat_gui)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.demomat_gui)
        end
    end
end